classdef group43engfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        timebeingplayedEditField       matlab.ui.control.NumericEditField
        timebeingplayedEditFieldLabel  matlab.ui.control.Label
        FrequencyLabel                 matlab.ui.control.Label
        TIMESPLAYEDLabel               matlab.ui.control.Label
        CURRENTLYPLAYINGLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        VolumeLevelEditField           matlab.ui.control.NumericEditField
        DropDown                       matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        next                           matlab.ui.control.Button
        pausesong                      matlab.ui.control.Button
        playsong                       matlab.ui.control.Button
        playbackwards                  matlab.ui.control.Button
        previous                       matlab.ui.control.Button
        ClearplaycountButton           matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        streamsongbeingshared          matlab.ui.control.Button
        share                          matlab.ui.control.Button
        DropDown_2                     matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ySound
        sampleFreq
        startupFcn
        t
        songSelected
        stop
        lengthOfsample
        player
        currentTime
        plan
        CurrentSample
        graph
        back =0;
        num1 =0;
        num2 =0;
        num3 =0;
        num4 =0;
        num5 =0;
        num6 =0
        num7 =0;
        num8 =0;
        num9 =0;
        num10 =0;
        Length
        dT

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.plan = 0;
            app.SliderLabel.Text = "Volume";
            app.Slider.Value = 100;
            %app.UITable.Data(end+1,:) = table('All I Want for Christmas Is You','As It Was','Brown Eyed Girl','Dancing Queen', 'I Want It That Way');
            %app.UITable.Data(end+1,:) = table('Night Changes','Sweet Caroline','Take on Me','We Built This City','You Belong With Me (Taylors Version)');
       
            app.UITable2.Data(1,1) = 0;
        end

        % Button pushed function: playsong
        function playsongPushed(app, event)
            if app.plan == 0
                if app.back == 1
                    app.player = audioplayer(app.ySound,app.sampleFreq);
                    app.back = 0;
                end
                play(app.player);
                app.plan = 1 ;
            else
                playblocking(app.player, app.currentTime);
            end
        end

        % Button pushed function: pausesong
        function pausesongPushed(app, event)
            app.currentTime = app.player.CurrentSample;
            pause(app.player)
        end

        % Value changed function: DropDown
        function DropDownValue(app, event)
            value = app.DropDown.Value;
            app.currentTime = app.player.CurrentSample;

            pause(app.player);

            if value == ("2.00x")
                app.player = audioplayer(app.ySound, app.sampleFreq*2);
                playblocking(app.player, app.currentTime);

            elseif value == ("1.50x")
                app.player = audioplayer(app.ySound, app.sampleFreq*1.5);
                playblocking(app.player, app.currentTime);

            elseif value == ("1.00x")
                app.player = audioplayer(app.ySound, app.sampleFreq*1);
                playblocking(app.player, app.currentTime);

            elseif value == ("0.50x")
                app.player = audioplayer(app.ySound, app.sampleFreq*0.5);
                playblocking(app.player, app.currentTime);

            elseif value == ("0.25x")
                app.player = audioplayer(app.ySound, app.sampleFreq*0.25);
                playblocking(app.player, app.currentTime);
            end

        end

        % Button pushed function: playbackwards
        function Backwards(app, event)
            pause(app.player);
            switch (app.DropDown_2.Value)
                case "All I Want for Christmas is You"
                    [app.ySound, app.sampleFreq] = audioread(" All I Want for Christmas Is You.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                    
                case "As It Was"
                    [app.ySound, app.sampleFreq] = audioread("As It Was.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "Brown Eyed Girl"
                    [app.ySound, app.sampleFreq] = audioread(" Brown Eyed Girl.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "Dancing Queen"
                    [app.ySound, app.sampleFreq] = audioread(" Dancing Queen.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "I Want It That Way"
                    [app.ySound, app.sampleFreq] = audioread(" I Want It That Way.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "Night Changes"
                    [app.ySound, app.sampleFreq] = audioread(" Night Changes.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "Sweet Caroline"
                    [app.ySound, app.sampleFreq] = audioread(" Sweet Caroline.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "Take on Me"
                    [app.ySound, app.sampleFreq] = audioread(" Take on Me.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "We Built This City"
                    [app.ySound, app.sampleFreq] = audioread(" We Built This City.mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
                case "You Belong With Me"
                    [app.ySound, app.sampleFreq] = audioread(" You Belong With Me (Taylor’s Version).mp3");
                    app.player = audioplayer(app.ySound(end:-1:1),app.sampleFreq);
                    app.plan = 0;
                    app.back = 1;
            end
            play(app.player);
        end

        % Value changed function: DropDown_2
        function DropDown_2Value(app, event)
            value = app.DropDown_2.Value;
            if value == "All I Want for Christmas is You"
                [app.ySound, app.sampleFreq] = audioread(" All I Want for Christmas Is You.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num1 =app.num1+1;
                app.UITable2.Data(1,1) = app.num1;
   
                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value == "As It Was"
                [app.ySound, app.sampleFreq] = audioread("As It Was.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num2 =app.num2+1;
                app.UITable2.Data(1,1) = app.num2;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value == "Brown Eyed Girl"
                [app.ySound, app.sampleFreq] = audioread(" Brown Eyed Girl.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num3 =app.num3+1;
                app.UITable2.Data(1,1) = app.num3;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value== "Dancing Queen"
                [app.ySound, app.sampleFreq] = audioread(" Dancing Queen.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num4 =app.num4+1;
                app.UITable2.Data(1,1) = app.num4;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value== "I Want It That Way"
                [app.ySound, app.sampleFreq] = audioread(" I Want It That Way.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num5 =app.num5+1;
                app.UITable2.Data(1,1) = app.num5;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value== "Night Changes"
                [app.ySound, app.sampleFreq] = audioread(" Night Changes.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num6 =app.num6+1;
                app.UITable2.Data(1,1) = app.num6;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value== "Sweet Caroline"
                [app.ySound, app.sampleFreq] = audioread(" Sweet Caroline.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num7 =app.num7+1;
                app.UITable2.Data(1,1) = app.num7;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value== "Take on Me"
                [app.ySound, app.sampleFreq] = audioread(" Take on Me.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num8 =app.num8+1;
                app.UITable2.Data(1,1) = app.num8;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value== "We Built This City"
                [app.ySound, app.sampleFreq] = audioread(" We Built This City.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num9 =app.num9+1;
                app.UITable2.Data(1,1) = app.num9;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

            elseif value=="You Belong With Me"
                [app.ySound, app.sampleFreq] = audioread(" You Belong With Me (Taylor’s Version).mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.num10 =app.num10+1;
                app.UITable2.Data(1,1) = app.num10;

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);
                
            end

        end

        % Button pushed function: previous
        function previousPushed(app, event)
            pause(app.player)
            if app.DropDown_2.Value== ("All I Want for Christmas is You")
                [app.ySound, app.sampleFreq] = audioread(" You Belong With Me (Taylor’s Version).mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "You Belong With Me";
                
                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num1=app.num1 +1;

            elseif app.DropDown_2.Value== ("As It Was")
                [app.ySound, app.sampleFreq] = audioread(" All I Want for Christmas Is You.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "All I Want for Christmas Is You";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num2=app.num2 +1;

            elseif app.DropDown_2.Value== ("Brown Eyed Girl")
                [app.ySound, app.sampleFreq] = audioread("As It Was.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "As It Was";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num3=app.num3 +1;

            elseif app.DropDown_2.Value== ("Dancing Queen")
                [app.ySound, app.sampleFreq] = audioread(" Brown Eyed Girl.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value="Brown Eyed Girl";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num4=app.num4 +1;

            elseif app.DropDown_2.Value== ("I Want It That Way")
                [app.ySound, app.sampleFreq] = audioread(" Dancing Queen.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value="Dancing Queen";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num5=app.num5 +1;

            elseif app.DropDown_2.Value== ("Night Changes")
                [app.ySound, app.sampleFreq] = audioread(" I Want It That Way.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "I Want It That Way";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num6=app.num6 +1;

            elseif app.DropDown_2.Value== ("Sweet Caroline")
                [app.ySound, app.sampleFreq] = audioread(" Night Changes.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Night Changes";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num7=app.num7 +1;

            elseif app.DropDown_2.Value== ("Take on Me")
                [app.ySound, app.sampleFreq] = audioread(" Sweet Caroline.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Sweet Caroline";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num8=app.num8 +1;

            elseif app.DropDown_2.Value== ("We Built This City")
                [app.ySound, app.sampleFreq] = audioread(" Take on Me.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Take on Me";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num9=app.num9 +1;

            elseif app.DropDown_2.Value== ("You Belong With Me")
                [app.ySound, app.sampleFreq] = audioread(" We Built This City.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value="We Built This City";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num10=app.num10 +1;
            end
            play(app.player)
        end

        % Value changed function: Slider
        function SliderValue(app, event)
            value = app.Slider.Value;
            app.VolumeLevelEditField.Value = value;
            app.currentTime = app.player.CurrentSample;

            pause(app.player);

            app.player = audioplayer(app.ySound.*(value/100), app.sampleFreq);
            playblocking(app.player, app.currentTime);

        end

        % Button pushed function: next
        function nextPushed(app, event)
            pause(app.player);
            if app.DropDown_2.Value== ("All I Want for Christmas is You")
                [app.ySound, app.sampleFreq] = audioread("As It Was.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "As It Was";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num1=app.num1 +1;

            elseif app.DropDown_2.Value== ("As It Was")
                [app.ySound, app.sampleFreq] = audioread(" Brown Eyed Girl.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value="Brown Eyed Girl";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num2=app.num2 +1;

            elseif app.DropDown_2.Value== ("Brown Eyed Girl")
                [app.ySound, app.sampleFreq] = audioread(" Dancing Queen.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Dancing Queen";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num3=app.num3 +1;

            elseif app.DropDown_2.Value== ("Dancing Queen")
                [app.ySound, app.sampleFreq] = audioread(" I Want It That Way.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "I Want It That Way";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num4=app.num4 +1;

            elseif app.DropDown_2.Value== ("I Want It That Way")
                [app.ySound, app.sampleFreq] = audioread(" Night Changes.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Night Changes";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num5=app.num5 +1;

            elseif app.DropDown_2.Value== ("Night Changes")
                [app.ySound, app.sampleFreq] = audioread(" Sweet Caroline.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Sweet Caroline";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num6=app.num6 +1;

            elseif  app.DropDown_2.Value== ("Sweet Caroline")
                [app.ySound, app.sampleFreq] = audioread(" Take on Me.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Take on Me";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num7=app.num7 +1;

            elseif app.DropDown_2.Value== ("Take on Me")
                [app.ySound, app.sampleFreq] = audioread(" We Built This City.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "We Built This City";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num8=app.num8 +1;

            elseif app.DropDown_2.Value== ("We Built This City")
                [app.ySound, app.sampleFreq] = audioread(" You Belong With Me (Taylor’s Version).mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "You Belong With Me";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num9=app.num9 +1;

            elseif app.DropDown_2.Value== ("You Belong With Me")
                [app.ySound, app.sampleFreq] = audioread(" All I Want for Christmas Is You.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "All I Want for Christmas is You";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num10=app.num10 +1;
            end 
            app.timebeingplayedEditField.Value = 0;
            play(app.player);
        end

        % Button pushed function: share
        function sharePushed(app, event)
           value =app.DropDown_2.Value;
            thingSpeakWrite(1948921,'WriteKey','P76NPIW3B4DD3UCI', 'Fields',1,'value', {value});
        end

        % Button pushed function: streamsongbeingshared
        function streamsongbeingsharedPushed(app, event)
         pause(app.player);
          value = thingSpeakRead(1948921,'ReadKey','P3DPSWEK8462MUV8', 'Fields',1,'OutputFormat','table');
          value = value.(2);
          value = string(value);
            if value == "All I Want for Christmas is You"
                [app.ySound, app.sampleFreq] = audioread(" All I Want for Christmas Is You.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "All I Want for Christmas is You";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num1=app.num1 +1;
                
            elseif value == "As It Was"
                [app.ySound, app.sampleFreq] = audioread("As It Was.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "As It Was";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;


                plot(app.UIAxes, app.Length, app.ySound);

                app.num2=app.num2 +1;

            elseif value == "Brown Eyed Girl"
                [app.ySound, app.sampleFreq] = audioread(" Brown Eyed Girl.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Brown Eyed Girl";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num3=app.num3 +1;
            elseif value== "Dancing Queen"
                [app.ySound, app.sampleFreq] = audioread(" Dancing Queen.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Dancing Queen";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num4=app.num4 +1;
            elseif value== "I Want It That Way"
                [app.ySound, app.sampleFreq] = audioread(" I Want It That Way.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "I Want It That Way";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num5=app.num5 +1;
            elseif value== "Night Changes"
                [app.ySound, app.sampleFreq] = audioread(" Night Changes.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Night Changes";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num6=app.num6 +1;
            elseif value== "Sweet Caroline"
                [app.ySound, app.sampleFreq] = audioread(" Sweet Caroline.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Sweet Caroline";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num7=app.num7 +1;
            elseif value== "Take on Me"
                [app.ySound, app.sampleFreq] = audioread(" Take on Me.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "Take on Me";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);
                
                app.num8=app.num8 +1;
            elseif value== "We Built This City"
                [app.ySound, app.sampleFreq] = audioread(" We Built This City.mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "We Built This City";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num9=app.num9 +1;
            elseif value=="You Belong With Me"
                [app.ySound, app.sampleFreq] = audioread(" You Belong With Me (Taylor’s Version).mp3");
                app.player = audioplayer(app.ySound,app.sampleFreq);
                app.plan = 0;
                app.DropDown_2.Value= "You Belong With Me";

                app.ySound=app.ySound(:,end);
                app.dT=1/app.sampleFreq;
                app.Length= 0:app.dT:(length(app.ySound)*app.dT)-app.dT;

                plot(app.UIAxes, app.Length, app.ySound);

                app.num10=app.num10 +1;
            end
            play(app.player);
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            pause(app.player);
            app.value = app.TextArea.Value;
            app.TextArea.Value =1;

            if value == "All I Want for Christmas is You"
                app.num1=app.num1 +1;
                app.TextArea.Value = app.num1;
            elseif value == "As It Was"
                app.num2=app.num2 +1;
                app.TextArea.Value = app.num2;
            elseif value == "Brown Eyed Girl"
                app.num3=app.num3 +1;
                app.TextArea.Value = app.num3;
            elseif value== "Dancing Queen"
                app.num4=app.num4 +1; 
                app.TextArea.Value = app.num4;
            elseif value== "I Want It That Way"
                app.num5=app.num5 +1;
                app.TextArea.Value = app.num5;
            elseif value== "Night Changes"
                app.num6=app.num6 +1; 
                app.TextArea.Value = app.num6;
            elseif value== "Sweet Caroline"
                app.num7=app.num7 +1;
                app.TextArea.Value = app.num7;
            elseif value== "Take on Me"
                app.num8=app.num8 +1; 
                app.TextArea.Value = app.num8;
            elseif value== "We Built This City"
                app.num9=app.num9 +1; 
                app.TextArea.Value = app.num9;
            elseif value=="You Belong With Me"
                app.num10=app.num10 +1; 
                app.TextArea.Value = app.num10;
            end
            
            play(app.player);

        end

        % Button pushed function: ClearplaycountButton
        function ClearplaycountButtonPushed(app, event)
            pause(app.player);
            
            app.num1 = 0;
            app.num2 = 0;
            app.num3 = 0;
            app.num4 = 0;
            app.num5 = 0;
            app.num6 = 0;
            app.num7 = 0;
            app.num8 = 0;
            app.num9 = 0;
            app.num10 = 0;
            play(app.player);


        end

        % Value changed function: timebeingplayedEditField
        function timebeingplayedEditFieldValueChanged(app, event)
            value = app.timebeingplayedEditField.Value;
              pause(app.player);
            app.value = app.TextArea.Value;
            app.TextArea.Value =1;

            if value == "All I Want for Christmas is You"
                app.num1=app.num1 +1;
                app.TextArea.Value = app.num1;
            elseif value == "As It Was"
                app.num2=app.num2 +1;
                app.TextArea.Value = app.num2;
            elseif value == "Brown Eyed Girl"
                app.num3=app.num3 +1;
                app.TextArea.Value = app.num3;
            elseif value== "Dancing Queen"
                app.num4=app.num4 +1; 
                app.TextArea.Value = app.num4;
            elseif value== "I Want It That Way"
                app.num5=app.num5 +1;
                app.TextArea.Value = app.num5;
            elseif value== "Night Changes"
                app.num6=app.num6 +1; 
                app.TextArea.Value = app.num6;
            elseif value== "Sweet Caroline"
                app.num7=app.num7 +1;
                app.TextArea.Value = app.num7;
            elseif value== "Take on Me"
                app.num8=app.num8 +1; 
                app.TextArea.Value = app.num8;
            elseif value== "We Built This City"
                app.num9=app.num9 +1; 
                app.TextArea.Value = app.num9;
            elseif value=="You Belong With Me"
                app.num10=app.num10 +1; 
                app.TextArea.Value = app.num10;
            end
            
            play(app.player);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'white')
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'All I Want for Christmas is You', 'As It Was', 'Brown Eyed Girl', 'Dancing Queen', 'I Want It That Way', 'Night Changes', 'Sweet Caroline', 'Take on Me', 'We Built This City', 'You Belong With Me'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2Value, true);
            app.DropDown_2.Tooltip = {'Select & change song'};
            app.DropDown_2.Placeholder = 'Select Song';
            app.DropDown_2.Value = 'All I Want for Christmas is You';
            app.share = uibutton(app.UIFigure, 'push');
            app.share.ButtonPushedFcn = createCallbackFcn(app, @sharePushed, true);
            app.share.Tooltip = {'Share'};
            app.share.Text = '';
            app.share.Icon = fullfile(pathToMLAPP, 'icons', 'share.svg');
            app.streamsongbeingshared = uibutton(app.UIFigure, 'push');
            app.streamsongbeingshared.ButtonPushedFcn = createCallbackFcn(app, @streamsongbeingsharedPushed, true);
            app.streamsongbeingshared.Tooltip = {'Stream shared song'};
            app.streamsongbeingshared.Text = '';
            app.streamsongbeingshared.Icon = fullfile(pathToMLAPP, 'icons', 'headphones.svg');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.Tooltip = {'Amount of times the song has been played'};
            app.ClearplaycountButton = uibutton(app.UIFigure, 'push');
            app.ClearplaycountButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplaycountButtonPushed, true);
            app.ClearplaycountButton.Text = 'Clear play count';
            app.Panel = uipanel(app.UIFigure);
            app.previous = uibutton(app.Panel, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousPushed, true);
            app.previous.Tooltip = {'Previous'};
            app.previous.Text = '';
            app.previous.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward (2).svg');
            app.playbackwards = uibutton(app.Panel, 'push');
            app.playbackwards.ButtonPushedFcn = createCallbackFcn(app, @Backwards, true);
            app.playbackwards.Tooltip = {'Play backwards'};
            app.playbackwards.Text = '';
            app.playbackwards.Icon = fullfile(pathToMLAPP, 'icons', 'flip 1.svg');
            app.playsong = uibutton(app.Panel, 'push');
            app.playsong.ButtonPushedFcn = createCallbackFcn(app, @playsongPushed, true);
            app.playsong.Tooltip = {'Play'};
            app.playsong.Text = '';
            app.playsong.Icon = fullfile(pathToMLAPP, 'icons', 'play 1.svg');
            app.pausesong = uibutton(app.Panel, 'push');
            app.pausesong.ButtonPushedFcn = createCallbackFcn(app, @pausesongPushed, true);
            app.pausesong.Tooltip = {'Pause'};
            app.pausesong.Text = '';
            app.pausesong.Icon = fullfile(pathToMLAPP, 'icons', 'pause (1).svg');
            app.next = uibutton(app.Panel, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextPushed, true);
            app.next.Tooltip = {'Next'};
            app.next.Text = '';
            app.next.Icon = fullfile(pathToMLAPP, 'icons', 'fast-forward (1).svg');
            app.SliderLabel = uilabel(app.Panel);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.Panel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValue, true);
            app.Slider.MinorTicks = [];
            app.Slider.Tooltip = {'Adjust volume'};
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'volume 1.svg');
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'0.25x', '0.50x', '1.00x', '1.50x', '2.00x'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValue, true);
            app.DropDown.Tooltip = {'Change playback speed'};
            app.DropDown.Value = '1.00x';
            app.VolumeLevelEditField = uieditfield(app.Panel, 'numeric');
            app.VolumeLevelEditField.Limits = [0 100];
            app.CURRENTLYPLAYINGLabel = uilabel(app.UIFigure);
            app.CURRENTLYPLAYINGLabel.Text = 'CURRENTLY PLAYING';
            app.TIMESPLAYEDLabel = uilabel(app.UIFigure);
            app.TIMESPLAYEDLabel.Text = 'TIMES PLAYED';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.timebeingplayedEditFieldLabel = uilabel(app.UIFigure);
            app.timebeingplayedEditFieldLabel.Text = 'time being played';
            app.timebeingplayedEditField = uieditfield(app.UIFigure, 'numeric');
            app.timebeingplayedEditField.ValueChangedFcn = createCallbackFcn(app, @timebeingplayedEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = group43engfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end